classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        inspectexistingprocessedfilesTab  matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        ConfigurationPanel             matlab.ui.container.Panel
        SelectbaseButton               matlab.ui.control.Button
        InstrumentDropDownLabel        matlab.ui.control.Label
        InstrumentDropDown             matlab.ui.control.DropDown
        GroupstoexcludePanel           matlab.ui.container.Panel
        detailledgroupstoexcludeListBoxLabel  matlab.ui.control.Label
        detailledgroupstoexcludeListBox  matlab.ui.control.ListBox
        functionalgroupstoexcludeListBoxLabel  matlab.ui.control.Label
        functionalgroupstoexcludeListBox  matlab.ui.control.ListBox
        trophicgroupstoexcludeListBoxLabel  matlab.ui.control.Label
        trophicgroupstoexcludeListBox  matlab.ui.control.ListBox
        SampleselectionPanel           matlab.ui.container.Panel
        AllsamplessequentiallyButton   matlab.ui.control.StateButton
        onesampleListBoxLabel          matlab.ui.control.Label
        onesampleListBox               matlab.ui.control.ListBox
        GraphappearancemodificationsPanel  matlab.ui.container.Panel
        ColormapListBoxLabel           matlab.ui.control.Label
        ColormapListBox                matlab.ui.control.ListBox
        MinimalsizeSliderLabel         matlab.ui.control.Label
        MinimalsizeSlider              matlab.ui.control.Slider
        MaximalsizeSliderLabel         matlab.ui.control.Label
        MaximalsizeSlider              matlab.ui.control.Slider
        SavePanel                      matlab.ui.container.Panel
        SavegraphButton                matlab.ui.control.Button
        ResolutionSliderLabel          matlab.ui.control.Label
        UIAxes_8                       matlab.ui.control.UIAxes
        UIAxes_9                       matlab.ui.control.UIAxes
        UIAxes_10                      matlab.ui.control.UIAxes
        UIAxes_11                      matlab.ui.control.UIAxes
        UIAxes_12                      matlab.ui.control.UIAxes
        UIAxes_13                      matlab.ui.control.UIAxes
        UIAxes_14                      matlab.ui.control.UIAxes
        UIAxes_15                      matlab.ui.control.UIAxes
        UIAxes_16                      matlab.ui.control.UIAxes
        ResolutionSlider               matlab.ui.control.Slider
        TypeoffileListBoxLabel         matlab.ui.control.Label
        TypeoffileListBox              matlab.ui.control.ListBox
        UIAxes_7                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data % Description
        listspecies
        listfunctions
        listtrophic
        subsetdataAb
        subsetdataBv
        listspeciesshort
        listfunctionsshort
        listtrophicshort
        listesamples
    end
    
    methods (Access = private)
        
        function results = refreshplot(app)
            
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
            percentValues=floor(app.subsetdataAb/sum(app.subsetdataAb));
            combinedtxt = strcat(app.listspeciesshort,num2str(percentValues)); 
            p=pie(app.UIAxes,app.subsetdataAb/sum(app.subsetdataAb),combinedtxt); %,app.listspeciesshort,

        end
    end
    

    methods (Access = private)

        % Callback function: SampleselectionPanel, SelectbaseButton
        function SelectbaseButtonPushed(app, event)
            f = msgbox('Please select the processed bases to analyze');
            uiwait(f)
            [file,path] = uigetfile('*.mat');
            data=load([path file]);
            fields = fieldnames(data);
        eval(['data=data.' char(fields) ';'])
        app.data=data;
        
        
        app.listspecies=app.data(1).regroupped.Idlistshort;
        app.listfunctions=app.data(1).regroupped.Zoo_groups(app.data(1).regroupped.pftplace(1):app.data(1).regroupped.pftplace(2));
        app.listtrophic=app.data(1).regroupped.Zoo_groups(app.data(1).regroupped.trophicplace(1):app.data(1).regroupped.trophicplace(2));
        
        listsamples=[app.data.SampleID];
        app.onesampleListBox.Items =listsamples';
        app.detailledgroupstoexcludeListBox.Items =app.listspecies;
        app.functionalgroupstoexcludeListBox.Items =app.listfunctions;
        app.trophicgroupstoexcludeListBox.Items =app.listtrophic;
        
        end

        % Value changed function: onesampleListBox
        function onesampleListBoxValueChanged(app, event)
            value = app.onesampleListBox.Value;
            listsamples=[app.data.SampleID];
            I=strcmp(listsamples',value);
            app.subsetdataAb=app.data(I).regroupped.Ab(app.data(1).regroupped.originalplace(1):app.data(1).regroupped.originalplace(2));  
            app.subsetdataBv=app.data(I).regroupped.Bv;  
            result=refreshplot(app)
        end

        % Value changed function: detailledgroupstoexcludeListBox
        function detailledgroupstoexcludeListBoxValueChanged(app, event)
            value = app.detailledgroupstoexcludeListBox.Value;
            %app.listspeciesshort=app.listspecies;
            %app.listspeciesshort(value)=[];
            
        end

        % Value changed function: functionalgroupstoexcludeListBox
        function functionalgroupstoexcludeListBoxValueChanged(app, event)
            value = app.functionalgroupstoexcludeListBox.Value;
                        %app.listfunctionsshort=app.listfunctions;
            %app.listfunctionsshort(value)=[];
            
        end

        % Value changed function: trophicgroupstoexcludeListBox
        function trophicgroupstoexcludeListBoxValueChanged(app, event)
            value = app.trophicgroupstoexcludeListBox.Value;
                                    %app.listtrophicshort=app.listtrophic;
            %app.listtrophicshort(value)=[];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.inspectexistingprocessedfilesTab = uitab(app.TabGroup);
            app.inspectexistingprocessedfilesTab.Title = 'inspect existing processed files';
            app.UIAxes = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes, 'Abundance')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_2, 'Biovolumes')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.ConfigurationPanel = uipanel(app.inspectexistingprocessedfilesTab);
            app.ConfigurationPanel.Title = 'Configuration';
            app.SelectbaseButton = uibutton(app.ConfigurationPanel, 'push');
            app.SelectbaseButton.ButtonPushedFcn = createCallbackFcn(app, @SelectbaseButtonPushed, true);
            app.SelectbaseButton.Text = 'Select base';
            app.InstrumentDropDownLabel = uilabel(app.ConfigurationPanel);
            app.InstrumentDropDownLabel.Text = 'Instrument';
            app.InstrumentDropDown = uidropdown(app.ConfigurationPanel);
            app.InstrumentDropDown.Items = {'Zooscan', 'Flowcam', 'IFCB', 'UVP (detailled)', 'UVP (detailled+ecotaxa)'};
            app.InstrumentDropDown.Value = 'Zooscan';
            app.GroupstoexcludePanel = uipanel(app.ConfigurationPanel);
            app.GroupstoexcludePanel.Title = 'Groups to exclude';
            app.detailledgroupstoexcludeListBoxLabel = uilabel(app.GroupstoexcludePanel);
            app.detailledgroupstoexcludeListBoxLabel.Text = 'detailled groups to exclude';
            app.detailledgroupstoexcludeListBox = uilistbox(app.GroupstoexcludePanel);
            app.detailledgroupstoexcludeListBox.Multiselect = 'on';
            app.detailledgroupstoexcludeListBox.ValueChangedFcn = createCallbackFcn(app, @detailledgroupstoexcludeListBoxValueChanged, true);
            app.detailledgroupstoexcludeListBox.Value = {};
            app.functionalgroupstoexcludeListBoxLabel = uilabel(app.GroupstoexcludePanel);
            app.functionalgroupstoexcludeListBoxLabel.Text = 'functional groups to exclude';
            app.functionalgroupstoexcludeListBox = uilistbox(app.GroupstoexcludePanel);
            app.functionalgroupstoexcludeListBox.Multiselect = 'on';
            app.functionalgroupstoexcludeListBox.ValueChangedFcn = createCallbackFcn(app, @functionalgroupstoexcludeListBoxValueChanged, true);
            app.functionalgroupstoexcludeListBox.Value = {};
            app.trophicgroupstoexcludeListBoxLabel = uilabel(app.GroupstoexcludePanel);
            app.trophicgroupstoexcludeListBoxLabel.Text = 'trophic groups to exclude';
            app.trophicgroupstoexcludeListBox = uilistbox(app.GroupstoexcludePanel);
            app.trophicgroupstoexcludeListBox.Multiselect = 'on';
            app.trophicgroupstoexcludeListBox.ValueChangedFcn = createCallbackFcn(app, @trophicgroupstoexcludeListBoxValueChanged, true);
            app.trophicgroupstoexcludeListBox.Value = {};
            app.SampleselectionPanel = uipanel(app.ConfigurationPanel);
            app.SampleselectionPanel.Title = 'Sample selection';
            app.SampleselectionPanel.SizeChangedFcn = createCallbackFcn(app, @SelectbaseButtonPushed, true);
            app.AllsamplessequentiallyButton = uibutton(app.SampleselectionPanel, 'state');
            app.AllsamplessequentiallyButton.Text = 'All samples sequentially';
            app.onesampleListBoxLabel = uilabel(app.SampleselectionPanel);
            app.onesampleListBoxLabel.Text = 'one sample';
            app.onesampleListBox = uilistbox(app.SampleselectionPanel);
            app.onesampleListBox.Multiselect = 'on';
            app.onesampleListBox.ValueChangedFcn = createCallbackFcn(app, @onesampleListBoxValueChanged, true);
            app.onesampleListBox.Value = {};
            app.GraphappearancemodificationsPanel = uipanel(app.ConfigurationPanel);
            app.GraphappearancemodificationsPanel.Title = 'Graph appearance modifications';
            app.ColormapListBoxLabel = uilabel(app.GraphappearancemodificationsPanel);
            app.ColormapListBoxLabel.Text = 'Colormap';
            app.ColormapListBox = uilistbox(app.GraphappearancemodificationsPanel);
            app.ColormapListBox.Value = {};
            app.MinimalsizeSliderLabel = uilabel(app.GraphappearancemodificationsPanel);
            app.MinimalsizeSliderLabel.Text = 'Minimal size';
            app.MinimalsizeSlider = uislider(app.GraphappearancemodificationsPanel);
            app.MaximalsizeSliderLabel = uilabel(app.GraphappearancemodificationsPanel);
            app.MaximalsizeSliderLabel.Text = 'Maximal size';
            app.MaximalsizeSlider = uislider(app.GraphappearancemodificationsPanel);
            app.SavePanel = uipanel(app.ConfigurationPanel);
            app.SavePanel.Title = 'Save';
            app.SavegraphButton = uibutton(app.SavePanel, 'push');
            app.SavegraphButton.Text = 'Save graph';
            app.ResolutionSliderLabel = uilabel(app.SavePanel);
            app.ResolutionSliderLabel.Text = 'Resolution';
            app.ResolutionSlider = uislider(app.SavePanel);
            app.TypeoffileListBoxLabel = uilabel(app.SavePanel);
            app.TypeoffileListBoxLabel.Text = 'Type of file';
            app.TypeoffileListBox = uilistbox(app.SavePanel);
            app.TypeoffileListBox.Items = {'jpg', 'svg'};
            app.TypeoffileListBox.Value = 'jpg';
            app.UIAxes_7 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_7, 'NBSS')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            app.UIAxes_8 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_8, 'Biomass spectra')
            xlabel(app.UIAxes_8, 'X')
            ylabel(app.UIAxes_8, 'Y')
            app.UIAxes_9 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_9, 'Abundance')
            xlabel(app.UIAxes_9, 'X')
            ylabel(app.UIAxes_9, 'Y')
            app.UIAxes_10 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_10, 'Biovolumes')
            xlabel(app.UIAxes_10, 'X')
            ylabel(app.UIAxes_10, 'Y')
            app.UIAxes_11 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_11, 'NBSS')
            xlabel(app.UIAxes_11, 'X')
            ylabel(app.UIAxes_11, 'Y')
            app.UIAxes_12 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_12, 'Biomass spectra')
            xlabel(app.UIAxes_12, 'X')
            ylabel(app.UIAxes_12, 'Y')
            app.UIAxes_13 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_13, 'Abundance')
            xlabel(app.UIAxes_13, 'X')
            ylabel(app.UIAxes_13, 'Y')
            app.UIAxes_14 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_14, 'Biovolumes')
            xlabel(app.UIAxes_14, 'X')
            ylabel(app.UIAxes_14, 'Y')
            app.UIAxes_15 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_15, 'NBSS')
            xlabel(app.UIAxes_15, 'X')
            ylabel(app.UIAxes_15, 'Y')
            app.UIAxes_16 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_16, 'Biomass spectra')
            xlabel(app.UIAxes_16, 'X')
            ylabel(app.UIAxes_16, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end