classdef exotaxa_inspect1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        inspectexistingprocessedfilesTab  matlab.ui.container.Tab
        ConfigurationPanel             matlab.ui.container.Panel
        SelectbaseButton               matlab.ui.control.Button
        InstrumentDropDownLabel        matlab.ui.control.Label
        InstrumentDropDown             matlab.ui.control.DropDown
        SavePanel                      matlab.ui.container.Panel
        SavegraphButton                matlab.ui.control.Button
        ResolutionSliderLabel          matlab.ui.control.Label
        ResolutionSlider               matlab.ui.control.Slider
        TypeoffileListBoxLabel         matlab.ui.control.Label
        TypeoffileListBox              matlab.ui.control.ListBox
        SampleselectionPanel_2         matlab.ui.container.Panel
        AllsamplessequentiallyButton   matlab.ui.control.StateButton
        onesampleListBoxLabel          matlab.ui.control.Label
        onesampleListBox               matlab.ui.control.ListBox
        GraphappearancemodificationsPanel  matlab.ui.container.Panel
        ColormapListBoxLabel           matlab.ui.control.Label
        ColormapListBox                matlab.ui.control.ListBox
        proportionsSwitchLabel         matlab.ui.control.Label
        proportionsSwitch              matlab.ui.control.Switch
        TextSwitchLabel                matlab.ui.control.Label
        TextSwitch                     matlab.ui.control.Switch
        GroupstoexcludePanel_2         matlab.ui.container.Panel
        trophicgroupstoexcludeListBoxLabel  matlab.ui.control.Label
        trophicgroupstoexcludeListBox  matlab.ui.control.ListBox
        functionalgroupstoexcludeListBoxLabel  matlab.ui.control.Label
        functionalgroupstoexcludeListBox  matlab.ui.control.ListBox
        detailledgroupstoexcludeListBoxLabel  matlab.ui.control.Label
        detailledgroupstoexcludeListBox  matlab.ui.control.ListBox
        OriginalidentificationLabel    matlab.ui.control.Label
        functionaltypesLabel           matlab.ui.control.Label
        AbundancesLabel                matlab.ui.control.Label
        TrophictypesLabel              matlab.ui.control.Label
        BiovolumesLabel                matlab.ui.control.Label
        NBSSLabel                      matlab.ui.control.Label
        BiomassspectraLabel            matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_5                       matlab.ui.control.UIAxes
        UIAxes_6                       matlab.ui.control.UIAxes
        UIAxes_7                       matlab.ui.control.UIAxes
        UIAxes_8                       matlab.ui.control.UIAxes
        UIAxes_9                       matlab.ui.control.UIAxes
        Tab2                           matlab.ui.container.Tab
    end

    
    properties (Access = public)
        
        data % all data
        
        listspecies
        listfunctions
        listtrophic
        
        %extract from current sample
        subsetdataAb
        subsetdataAbshort
        subsetdataAbpft
        subsetdataAbtrophic
        subsetdataBv
        subsetdataBvshort
        subsetdataBvpft
        subsetdataBvtrophic
        
        listspeciesshort
        listfunctionsshort
        listtrophicshort
        listesamples
    end
    
    methods (Access = private)
        
        function refreshplot(app)
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
            
            switch app.proportionsSwitch.Value
                
                case 'Pie'
                    
                    switch app.TextSwitch.Value
                        case 'Off'
                            pie(app.UIAxes,100*app.subsetdataAb/sum(app.subsetdataAb));
                            n=length(app.subsetdataAb);
                            colors = (jet(n)+1)/2;
                            colormap(app.UIAxes,colors)
                        case 'On'
                            
                            pie(app.UIAxes,100*app.subsetdataAb/sum(app.subsetdataAb),app.listspeciesshort); %,app.listspeciesshort,
                            n=length(app.subsetdataAb);
                            colors = (jet(n)+1)/2;
                            colormap(app.UIAxes,colors)
                    end


                case 'Treemap'
                    switch app.TextSwitch.Value
                        case 'Off'
                            n=length(app.subsetdataAb);
                            colors = (jet(n)+1)/2;
                            rectangles = treemap(app.subsetdataAb);
                            plotRectanglesui(app.UIAxes,rectangles,[],colors);
                            
                            
                        case 'On'
                            n=length(app.subsetdataAb);
                            colors = (jet(n)+1)/2;
                            rectangles = treemap(app.subsetdataAb);
                            plotRectanglesui(app.UIAxes,rectangles,app.listspeciesshort,colors);
                    end
                    
            end
            
            
        end
    end
    

    methods (Access = private)

        % Button pushed function: SelectbaseButton
        function SelectbaseButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            data=load([path file]);
            fields = fieldnames(data);
            eval(['data=data.' char(fields) ';'])
            app.data=data;
            
            
            app.listspecies=app.data(1).regroupped.Idlistshort;
            app.listfunctions=app.data(1).regroupped.Zoo_groups(app.data(1).regroupped.pftplace(1):app.data(1).regroupped.pftplace(2));
            app.listtrophic=app.data(1).regroupped.Zoo_groups(app.data(1).regroupped.trophicplace(1):app.data(1).regroupped.trophicplace(2));
            app.listspeciesshort=app.listspecies;
            app.listfunctionsshort=app.listfunctions;
            app.listtrophicshort=app.listtrophic;
            
            listsamples=[app.data.SampleID];
            app.onesampleListBox.Items =listsamples';
            app.detailledgroupstoexcludeListBox.Items =app.listspecies;
            app.functionalgroupstoexcludeListBox.Items =app.listfunctions;
            app.trophicgroupstoexcludeListBox.Items =app.listtrophic;
        end

        % Value changed function: onesampleListBox
        function onesampleListBoxValueChanged(app, event)
            
             value = app.onesampleListBox.Value;
            listsamples=[app.data.SampleID];
            I=strcmp(listsamples',value);
            app.subsetdataAb=app.data(I).regroupped.Ab(app.data(1).regroupped.originalplace(1):app.data(1).regroupped.originalplace(2));
            app.subsetdataBv=app.data(I).regroupped.Bv;
            refreshplot(app);
        end

        % Value changed function: TextSwitch
        function TextSwitchValueChanged(app, event)
            %value = app.TextSwitch.Value;
              refreshplot(app);
        end

        % Value changed function: proportionsSwitch
        function proportionsSwitchValueChanged(app, event)
            %value = app.proportionsSwitch.Value;
              refreshplot(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.inspectexistingprocessedfilesTab = uitab(app.TabGroup);
            app.inspectexistingprocessedfilesTab.Title = 'inspect existing processed files';
            app.ConfigurationPanel = uipanel(app.inspectexistingprocessedfilesTab);
            app.ConfigurationPanel.Title = 'Configuration';
            app.SelectbaseButton = uibutton(app.ConfigurationPanel, 'push');
            app.SelectbaseButton.ButtonPushedFcn = createCallbackFcn(app, @SelectbaseButtonPushed, true);
            app.SelectbaseButton.Text = 'Select base';
            app.InstrumentDropDownLabel = uilabel(app.ConfigurationPanel);
            app.InstrumentDropDownLabel.Text = 'Instrument';
            app.InstrumentDropDown = uidropdown(app.ConfigurationPanel);
            app.InstrumentDropDown.Items = {'Zooscan', 'Flowcam', 'UVP (detailled)', 'UVP (detailled + ecotaxa)', 'IFCB'};
            app.InstrumentDropDown.Value = 'Zooscan';
            app.SavePanel = uipanel(app.ConfigurationPanel);
            app.SavePanel.Title = 'Save';
            app.SavegraphButton = uibutton(app.SavePanel, 'push');
            app.SavegraphButton.Text = 'Save graph';
            app.ResolutionSliderLabel = uilabel(app.SavePanel);
            app.ResolutionSliderLabel.Text = 'Resolution';
            app.ResolutionSlider = uislider(app.SavePanel);
            app.TypeoffileListBoxLabel = uilabel(app.SavePanel);
            app.TypeoffileListBoxLabel.Text = 'Type of file';
            app.TypeoffileListBox = uilistbox(app.SavePanel);
            app.TypeoffileListBox.Items = {'jpg', 'svg'};
            app.TypeoffileListBox.Value = 'jpg';
            app.SampleselectionPanel_2 = uipanel(app.ConfigurationPanel);
            app.SampleselectionPanel_2.Title = 'Sample selection';
            app.AllsamplessequentiallyButton = uibutton(app.SampleselectionPanel_2, 'state');
            app.AllsamplessequentiallyButton.Text = 'All samples sequentially';
            app.onesampleListBoxLabel = uilabel(app.SampleselectionPanel_2);
            app.onesampleListBoxLabel.Text = 'one sample';
            app.onesampleListBox = uilistbox(app.SampleselectionPanel_2);
            app.onesampleListBox.Multiselect = 'on';
            app.onesampleListBox.ValueChangedFcn = createCallbackFcn(app, @onesampleListBoxValueChanged, true);
            app.onesampleListBox.Value = {};
            app.GraphappearancemodificationsPanel = uipanel(app.ConfigurationPanel);
            app.GraphappearancemodificationsPanel.Title = 'Graph appearance modifications';
            app.ColormapListBoxLabel = uilabel(app.GraphappearancemodificationsPanel);
            app.ColormapListBoxLabel.Text = 'Colormap';
            app.ColormapListBox = uilistbox(app.GraphappearancemodificationsPanel);
            app.ColormapListBox.Value = {};
            app.proportionsSwitchLabel = uilabel(app.GraphappearancemodificationsPanel);
            app.proportionsSwitchLabel.Text = 'proportions';
            app.proportionsSwitch = uiswitch(app.GraphappearancemodificationsPanel, 'slider');
            app.proportionsSwitch.Items = {'Pie', 'Treemap'};
            app.proportionsSwitch.ValueChangedFcn = createCallbackFcn(app, @proportionsSwitchValueChanged, true);
            app.proportionsSwitch.Value = 'Pie';
            app.TextSwitchLabel = uilabel(app.GraphappearancemodificationsPanel);
            app.TextSwitchLabel.Text = 'Text';
            app.TextSwitch = uiswitch(app.GraphappearancemodificationsPanel, 'slider');
            app.TextSwitch.ValueChangedFcn = createCallbackFcn(app, @TextSwitchValueChanged, true);
            app.GroupstoexcludePanel_2 = uipanel(app.inspectexistingprocessedfilesTab);
            app.GroupstoexcludePanel_2.Title = 'Groups to exclude';
            app.trophicgroupstoexcludeListBoxLabel = uilabel(app.GroupstoexcludePanel_2);
            app.trophicgroupstoexcludeListBoxLabel.Text = 'trophic groups to exclude';
            app.trophicgroupstoexcludeListBox = uilistbox(app.GroupstoexcludePanel_2);
            app.trophicgroupstoexcludeListBox.Multiselect = 'on';
            app.trophicgroupstoexcludeListBox.Value = {};
            app.functionalgroupstoexcludeListBoxLabel = uilabel(app.GroupstoexcludePanel_2);
            app.functionalgroupstoexcludeListBoxLabel.Text = 'functional groups to exclude';
            app.functionalgroupstoexcludeListBox = uilistbox(app.GroupstoexcludePanel_2);
            app.functionalgroupstoexcludeListBox.Multiselect = 'on';
            app.functionalgroupstoexcludeListBox.Value = {};
            app.detailledgroupstoexcludeListBoxLabel = uilabel(app.GroupstoexcludePanel_2);
            app.detailledgroupstoexcludeListBoxLabel.Text = 'detailled groups to exclude';
            app.detailledgroupstoexcludeListBox = uilistbox(app.GroupstoexcludePanel_2);
            app.detailledgroupstoexcludeListBox.Multiselect = 'on';
            app.detailledgroupstoexcludeListBox.Value = {};
            app.OriginalidentificationLabel = uilabel(app.inspectexistingprocessedfilesTab);
            app.OriginalidentificationLabel.Text = 'Original identification';
            app.functionaltypesLabel = uilabel(app.inspectexistingprocessedfilesTab);
            app.functionaltypesLabel.Text = 'functional types';
            app.AbundancesLabel = uilabel(app.inspectexistingprocessedfilesTab);
            app.AbundancesLabel.Text = 'Abundances';
            app.TrophictypesLabel = uilabel(app.inspectexistingprocessedfilesTab);
            app.TrophictypesLabel.Text = 'Trophic types';
            app.BiovolumesLabel = uilabel(app.inspectexistingprocessedfilesTab);
            app.BiovolumesLabel.Text = 'Biovolumes';
            app.NBSSLabel = uilabel(app.inspectexistingprocessedfilesTab);
            app.NBSSLabel.Text = 'NBSS';
            app.BiomassspectraLabel = uilabel(app.inspectexistingprocessedfilesTab);
            app.BiomassspectraLabel.Text = 'Biomass spectra';
            app.UIAxes = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_4 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_4, '')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.UIAxes_5 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_5, '')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            app.UIAxes_6 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_6, '')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            app.UIAxes_7 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_7, '')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            app.UIAxes_8 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_8, '')
            xlabel(app.UIAxes_8, 'X')
            ylabel(app.UIAxes_8, 'Y')
            app.UIAxes_9 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_9, '')
            xlabel(app.UIAxes_9, 'X')
            ylabel(app.UIAxes_9, 'Y')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = exotaxa_inspect1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end