classdef exotaxa_inspect5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        inspectexistingprocessedfilesTab  matlab.ui.container.Tab
        ConfigurationPanel              matlab.ui.container.Panel
        SelectbaseButton                matlab.ui.control.Button
        InstrumentDropDownLabel         matlab.ui.control.Label
        InstrumentDropDown              matlab.ui.control.DropDown
        SampleselectionPanel_2          matlab.ui.container.Panel
        onesampleListBoxLabel           matlab.ui.control.Label
        onesampleListBox                matlab.ui.control.ListBox
        GraphappearancemodificationsPanel  matlab.ui.container.Panel
        ColormaporiginalListBoxLabel    matlab.ui.control.Label
        ColormaporiginalListBox         matlab.ui.control.ListBox
        proportionsSwitchLabel          matlab.ui.control.Label
        proportionsSwitch               matlab.ui.control.Switch
        TextCheckBox                    matlab.ui.control.CheckBox
        PercentagesCheckBox             matlab.ui.control.CheckBox
        NBSSSwitchLabel                 matlab.ui.control.Label
        NBSSSwitch                      matlab.ui.control.Switch
        ESDminEditFieldLabel            matlab.ui.control.Label
        ESDminEditField                 matlab.ui.control.NumericEditField
        ESDmaxEditFieldLabel            matlab.ui.control.Label
        ESDmaxEditField                 matlab.ui.control.NumericEditField
        ColormapfunctionalListBoxLabel  matlab.ui.control.Label
        ColormapfunctionalListBox       matlab.ui.control.ListBox
        ColormaptrophicListBoxLabel     matlab.ui.control.Label
        ColormaptrophicListBox          matlab.ui.control.ListBox
        NBSSCheckBox                    matlab.ui.control.CheckBox
        SavePanel                       matlab.ui.container.Panel
        SavegraphButton                 matlab.ui.control.Button
        ResolutionSliderLabel           matlab.ui.control.Label
        ResolutionSlider                matlab.ui.control.Slider
        TypeoffileListBoxLabel          matlab.ui.control.Label
        TypeoffileListBox               matlab.ui.control.ListBox
        AllsamplessequentiallyButton    matlab.ui.control.StateButton
        GroupstoexcludePanel_2          matlab.ui.container.Panel
        trophicgroupstoexcludeListBoxLabel  matlab.ui.control.Label
        trophicgroupstoexcludeListBox   matlab.ui.control.ListBox
        functionalgroupstoexcludeListBoxLabel  matlab.ui.control.Label
        functionalgroupstoexcludeListBox  matlab.ui.control.ListBox
        detailledgroupstoexcludeListBoxLabel  matlab.ui.control.Label
        detailledgroupstoexcludeListBox  matlab.ui.control.ListBox
        OriginalidentificationLabel     matlab.ui.control.Label
        functionaltypesLabel            matlab.ui.control.Label
        AbundancesLabel                 matlab.ui.control.Label
        TrophictypesLabel               matlab.ui.control.Label
        BiovolumesLabel                 matlab.ui.control.Label
        NBSSLabel                       matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes_9                        matlab.ui.control.UIAxes
        LegendLabel                     matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        GlobalrawanalysisTab            matlab.ui.container.Tab
        typeofanalysisPanel             matlab.ui.container.Panel
        ScaleSwitchLabel                matlab.ui.control.Label
        ScaleSwitch                     matlab.ui.control.Switch
        SpeciesListBoxLabel             matlab.ui.control.Label
        SpeciesListBox                  matlab.ui.control.ListBox
        unitsSwitchLabel                matlab.ui.control.Label
        unitsSwitch                     matlab.ui.control.Switch
        RangePanel                      matlab.ui.container.Panel
        MinlatEditFieldLabel            matlab.ui.control.Label
        MinlatEditField                 matlab.ui.control.NumericEditField
        MinlonEditFieldLabel            matlab.ui.control.Label
        MinlonEditField                 matlab.ui.control.NumericEditField
        MaxlatEditFieldLabel            matlab.ui.control.Label
        MaxlatEditField                 matlab.ui.control.NumericEditField
        MaxlonEditFieldLabel            matlab.ui.control.Label
        MaxlonEditField                 matlab.ui.control.NumericEditField
        ProjectionListBoxLabel          matlab.ui.control.Label
        ProjectionListBox               matlab.ui.control.ListBox
        GraphicoutputsPanel             matlab.ui.container.Panel
        SizeofpointsEditFieldLabel      matlab.ui.control.Label
        SizeofpointsEditField           matlab.ui.control.NumericEditField
        ColormapListBoxLabel            matlab.ui.control.Label
        ColormapListBox                 matlab.ui.control.ListBox
        LogscaleCheckBox                matlab.ui.control.CheckBox
        DrawButton                      matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        data % all data
        
        listspecies
        listpft
        listtrophic
        listtotal
        
        %extract from current sample
        subsetdataAb
        subsetdataAbshort
        subsetdataAbpft
        subsetdataAbtrophic
        subsetdataBv
        subsetdataBvshort
        subsetdataBvpft
        subsetdataBvtrophic
        
        subsetdataNBSS
        subsetdataNBSSshort
        subsetdataNBSSpft
        subsetdataNBSStrophic
        subsetdataNBSSpftshort
        subsetdataNBSStrophicshort
        
        includespecies
        includepft
        includetrophic
        
        listspeciesshort
        listpftshort
        listtrophicshort
        subsetdataAbpftshort
        subsetdataAbtrophicshort
        subsetdataBvpftshort
        subsetdataBvtrophicshort
        listesamples
        colormapspecies
        colormappft
        colormaptrophic
        
        
        BVsize
        ESDsize
    end
    
    methods (Access = private)
        %         assignin
        % export2wsdlg.
        
    end
    

    methods (Access = private)

        % Button pushed function: SelectbaseButton
        function SelectbaseButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            data=load([path file]);
            fields = fieldnames(data);
            eval(['data=data.' char(fields) ';'])
            app.data=data;
            
            
            app.listspecies=app.data(1).regroupped.Idlistshort(app.data(1).regroupped.originalplace(1):app.data(1).regroupped.originalplace(2));


            app.listpft=app.data(1).regroupped.Zoo_groups(app.data(1).regroupped.pftplace(1):app.data(1).regroupped.pftplace(2));
            I=strfind(app.listpft,'_');
            headershydrochar=char(app.listpft');
            [nnames mnames]=size(I);
            for i=1:nnames
                II=cell2mat(I(i));
                headershydrochar(i,II)=' ';
                app.listpft(i)=cellstr(headershydrochar(i,:));
                
                
            end
            
            
            app.listtrophic=app.data(1).regroupped.Zoo_groups(app.data(1).regroupped.trophicplace(1):app.data(1).regroupped.trophicplace(2));
            
            app.listtotal=[app.listspecies;app.listpft;app.listtrophic];
            app.SpeciesListBox.Items=app.listtotal;
            
            app.BVsize=exp(app.data(1).tot.X); % in mm3
            % app.ESDsize=app.data(1).tot.ESDvector; % in µm
            ESD=2*(app.BVsize*3/(4*pi)).^(1/3); % in mm
            app.ESDsize=ESD*1000; %in Âµm
            
            
            listsamples=[app.data.SampleID];
            app.onesampleListBox.Items =listsamples';
            app.detailledgroupstoexcludeListBox.Items =app.listspecies;
            app.functionalgroupstoexcludeListBox.Items =app.listpft;
            app.trophicgroupstoexcludeListBox.Items =app.listtrophic;
            
            % default exclusion to be inserted here
            if strcmp(app.InstrumentDropDown.Value,'Zooscan')==1
                
                
                value = {'Insecta','wing','seaweed','artefact','badfocus<artefact','bubble','detritus','fiber<detritus'};
            else
                value = {'detritus'};
                
            end
            
            I=string(app.listspecies)==string(value);
            I=sum(I,2);
            app.includespecies=I==0;
            app.detailledgroupstoexcludeListBox.Value= value;
            
            
            if strcmp(app.InstrumentDropDown.Value,'Zooscan')==1
                
                
                value = {'Cryptophyta','Phaeocystis','Dictyochophyceae','Chrysophyceae','ciliates','cyanobacteria','detritus','diatoms','dinoflagelates','flagellates'};
            else
                value = {'detritus'};
                
            end
            
            I=string(app.listpft)==string(value);
            I=sum(I,2);
            app.includepft=I==0;
            app.functionalgroupstoexcludeListBox.Value= value;
            
            value = {' -1'};
            I=string(app.listtrophic)==string(value);
            I=sum(I,2);
            app.includetrophic=I==0;
            app.trophicgroupstoexcludeListBox.Value= value;
            
            nsp=length(app.includespecies);
            value = app.ColormaporiginalListBox.Value;
            switch value
                
                case 'jet/2'
                     app.colormapspecies=(jet(nsp)+1)/2;
                    
                case 'HSV'
                    app.colormapspecies=(hsv(nsp)+1)/2;
                    
                case 'Spectral'
                    [colorformap]=cbrewer('div', 'Spectral',nsp);
                    app.colormapspecies=flipud(colorformap);
                    
            end
        
           app.colormappft=[vega20(20)];
            
            
            % assignin('base','apptemp',app);
            
        end

        % Value changed function: onesampleListBox
        function onesampleListBoxValueChanged(app, event)
            
            value = app.onesampleListBox.Value;
            listsamples=[app.data.SampleID];
            I=strcmp(listsamples',value);
            app.subsetdataAb=app.data(I).regroupped.Ab(app.data(1).regroupped.originalplace(1):app.data(1).regroupped.originalplace(2));
            app.subsetdataBv=app.data(I).regroupped.Bv(app.data(1).regroupped.originalplace(1):app.data(1).regroupped.originalplace(2));
            app.subsetdataAbpft=app.data(I).regroupped.Ab(app.data(1).regroupped.pftplace(1):app.data(1).regroupped.pftplace(2));
            app.subsetdataAbtrophic=app.data(I).regroupped.Ab(app.data(1).regroupped.trophicplace(1):app.data(1).regroupped.trophicplace(2));
            app.subsetdataBvpft=app.data(I).regroupped.Bv(app.data(1).regroupped.pftplace(1):app.data(1).regroupped.pftplace(2));
            app.subsetdataBvtrophic=app.data(I).regroupped.Bv(app.data(1).regroupped.trophicplace(1):app.data(1).regroupped.trophicplace(2));
            %assignin('base','apptemp',app);
            app.subsetdataNBSS=app.data(I).regroupped.Ybv_Ellipsoid_BV_spectra(:,app.data(1).regroupped.originalplace(1):app.data(1).regroupped.originalplace(2));
            
            app.subsetdataNBSSpft=app.data(I).regroupped.Ybv_Ellipsoid_BV_spectra(:,app.data(1).regroupped.pftplace(1):app.data(1).regroupped.pftplace(2));
            app.subsetdataNBSStrophic=app.data(I).regroupped.Ybv_Ellipsoid_BV_spectra(:,app.data(1).regroupped.trophicplace(1):app.data(1).regroupped.trophicplace(2));


            %shorter version with excluded data out
            app.subsetdataAbshort=app.subsetdataAb(app.includespecies);
            app.subsetdataBvshort=app.subsetdataBv(app.includespecies);
            app.subsetdataAbpftshort=app.subsetdataAbpft(app.includepft);
            app.subsetdataBvpftshort=app.subsetdataBvpft(app.includepft);
            app.subsetdataAbtrophicshort=app.subsetdataAbtrophic(app.includetrophic);
            
            app.subsetdataBvtrophicshort=app.subsetdataBvtrophic(app.includetrophic);
            
            app.subsetdataNBSSshort=app.subsetdataNBSS(:,app.includespecies);
            app.subsetdataNBSSpftshort= app.subsetdataNBSSpft(:,app.includepft);
            app.subsetdataNBSStrophicshort=app.subsetdataNBSStrophic(:,app.includetrophic);
            
            app.listspeciesshort=app.listspecies(app.includespecies);
            app.listpftshort=app.listpft(app.includepft);
            app.listtrophicshort=app.listtrophic(app.includetrophic);
            
            assignin('base','apptemp',app);
            refreshplot(app);
        end

        % Callback function
        function TextSwitchValueChanged(app, event)
            %value = app.TextSwitch.Value;
            refreshplot(app);
        end

        % Value changed function: proportionsSwitch
        function proportionsSwitchValueChanged(app, event)
            %value = app.proportionsSwitch.Value;
            refreshplot(app);
        end

        % Value changed function: detailledgroupstoexcludeListBox
        function detailledgroupstoexcludeListBoxValueChanged(app, event)
            value = app.detailledgroupstoexcludeListBox.Value;
            %             assignin('base','listspecies',app.listspecies);
            %             assignin('base','value',value);
            I=string(app.listspecies)==string(value);
            I=sum(I,2);
            app.includespecies=I==0;
            app.subsetdataAbshort=app.subsetdataAb(app.includespecies);
            app.subsetdataBvshort=app.subsetdataBv(app.includespecies);
            app.subsetdataNBSSshort=app.subsetdataNBSS(:,app.includespecies);
            app.listspeciesshort=app.listspecies(app.includespecies);
                        nsp=length(app.includespecies);
            value = app.ColormaporiginalListBox.Value;
            switch value
                
                case 'jet/2'
                     app.colormapspecies=(jet(nsp)+1)/2;
                    
                case 'HSV'
                    app.colormapspecies=(HSV(nsp)+1)/2;
                    
                case 'spectral'
                    [colorformap]=cbrewer('div', 'Spectral',nsp);
                    app.colormapspecies=flipud(colorformap);
                    
            end
            refreshplot(app);
        end

        % Value changed function: TextCheckBox
        function TextCheckBoxValueChanged(app, event)
            
            %assignin('base','value',value);
            refreshplot(app);
        end

        % Value changed function: PercentagesCheckBox
        function PercentagesCheckBoxValueChanged(app, event)
            refreshplot(app);
            
        end

        % Value changed function: functionalgroupstoexcludeListBox
        function functionalgroupstoexcludeListBoxValueChanged(app, event)
            value = app.functionalgroupstoexcludeListBox.Value;
            
            %             assignin('base','listspecies',app.listspecies);
            assignin('base','value',value);
            I=string(app.listpft)==string(value);
            I=sum(I,2);
            app.includepft=I==0;
            app.subsetdataAbpftshort=app.subsetdataAbpft(app.includepft);
            app.subsetdataBvpftshort=app.subsetdataBvpft(app.includepft);
            app.subsetdataNBSSpftshort= app.subsetdataNBSSpft(:,app.includepft);
            app.listpftshort=app.listpft(app.includepft);
            refreshplot(app);
        end

        % Value changed function: trophicgroupstoexcludeListBox
        function trophicgroupstoexcludeListBoxValueChanged(app, event)
            value = app.trophicgroupstoexcludeListBox.Value;
            
            
            %             assignin('base','listspecies',app.listspecies);
            assignin('base','value',value);
            I=string(app.listtrophic)==string(value);
            I=sum(I,2);
            app.includetrophic=I==0;
            app.subsetdataAbtrophicshort=app.subsetdataAbtrophic(app.includetrophic);
            app.subsetdataBvtrophicshort=app.subsetdataBvtrophic(app.includetrophic);
            app.subsetdataNBSStrophicshort=app.subsetdataNBSStrophic(:,app.includetrophic)
            app.listtrophicshort=app.listtrophic(app.includetrophic);
            refreshplot(app);
        end

        % Button pushed function: SavegraphButton
        function SavegraphButtonPushed(app, event)
            %savefig(app.UIFigure,'testfig.fig')
            %             I = getframe(app.UIFigure);
            %             imwrite(I.cdata, 'myplot.png');
        end

        % Value changed function: InstrumentDropDown
        function InstrumentDropDownValueChanged(app, event)
            value = app.InstrumentDropDown.Value;
            
        end

        % Value changed function: NBSSSwitch
        function NBSSSwitchValueChanged(app, event)
            value = app.NBSSSwitch.Value;
            refreshplot(app);
        end

        % Value changed function: ESDminEditField
        function ESDminEditFieldValueChanged(app, event)
            value = app.ESDminEditField.Value;
            refreshplot(app);
        end

        % Value changed function: ESDmaxEditField
        function ESDmaxEditFieldValueChanged(app, event)
            %value = app.ESDmaxEditField.Value;
            refreshplot(app);
        end

        % Value changed function: ColormaporiginalListBox
        function ColormaporiginalListBoxValueChanged(app, event)
            %value = app.ColormaporiginalListBox.Value;
                        nsp=length(app.includespecies);
            value = app.ColormaporiginalListBox.Value;
            switch value
                
                case 'jet/2'
                     app.colormapspecies=(jet(nsp)+1)/2;
                    
                case 'HSV'
                    app.colormapspecies=(hsv(nsp)+1)/2;
                    
                case 'Spectral'
                    [colorformap]=cbrewer('div', 'Spectral',nsp);
                    app.colormapspecies=flipud(colorformap);
                    
            end
            
            refreshplot(app);
        end

        % Value changed function: NBSSCheckBox
        function NBSSCheckBoxValueChanged(app, event)
            value = app.NBSSCheckBox.Value;
            refreshplot(app);
        end

        % Value changed function: SpeciesListBox
        function SpeciesListBoxValueChanged(app, event)
            value = app.SpeciesListBox.Value;
            listtotale=[app.listtotal];
            I=strcmp(listtotale,value);
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.inspectexistingprocessedfilesTab = uitab(app.TabGroup);
            app.inspectexistingprocessedfilesTab.Title = 'inspect existing processed files';
            app.ConfigurationPanel = uipanel(app.inspectexistingprocessedfilesTab);
            app.ConfigurationPanel.Title = 'Configuration';
            app.SelectbaseButton = uibutton(app.ConfigurationPanel, 'push');
            app.SelectbaseButton.ButtonPushedFcn = createCallbackFcn(app, @SelectbaseButtonPushed, true);
            app.SelectbaseButton.Text = 'Select base';
            app.InstrumentDropDownLabel = uilabel(app.ConfigurationPanel);
            app.InstrumentDropDownLabel.Text = 'Instrument';
            app.InstrumentDropDown = uidropdown(app.ConfigurationPanel);
            app.InstrumentDropDown.Items = {'Zooscan', 'Flowcam', 'UVP (detailled)', 'UVP (detailled + ecotaxa)', 'IFCB'};
            app.InstrumentDropDown.ValueChangedFcn = createCallbackFcn(app, @InstrumentDropDownValueChanged, true);
            app.InstrumentDropDown.Value = 'Zooscan';
            app.SampleselectionPanel_2 = uipanel(app.ConfigurationPanel);
            app.SampleselectionPanel_2.Title = 'Sample selection';
            app.onesampleListBoxLabel = uilabel(app.SampleselectionPanel_2);
            app.onesampleListBoxLabel.Text = 'one sample';
            app.onesampleListBox = uilistbox(app.SampleselectionPanel_2);
            app.onesampleListBox.Multiselect = 'on';
            app.onesampleListBox.ValueChangedFcn = createCallbackFcn(app, @onesampleListBoxValueChanged, true);
            app.onesampleListBox.Value = {};
            app.GraphappearancemodificationsPanel = uipanel(app.ConfigurationPanel);
            app.GraphappearancemodificationsPanel.Title = 'Graph appearance modifications';
            app.ColormaporiginalListBoxLabel = uilabel(app.GraphappearancemodificationsPanel);
            app.ColormaporiginalListBoxLabel.Text = 'Colormap original';
            app.ColormaporiginalListBox = uilistbox(app.GraphappearancemodificationsPanel);
            app.ColormaporiginalListBox.Items = {'jet/2', 'HSV', 'Spectral', 'Item 4'};
            app.ColormaporiginalListBox.ValueChangedFcn = createCallbackFcn(app, @ColormaporiginalListBoxValueChanged, true);
            app.ColormaporiginalListBox.Value = 'jet/2';
            app.proportionsSwitchLabel = uilabel(app.GraphappearancemodificationsPanel);
            app.proportionsSwitchLabel.Text = 'proportions';
            app.proportionsSwitch = uiswitch(app.GraphappearancemodificationsPanel, 'slider');
            app.proportionsSwitch.Items = {'Pie', 'Treemap'};
            app.proportionsSwitch.ValueChangedFcn = createCallbackFcn(app, @proportionsSwitchValueChanged, true);
            app.proportionsSwitch.Value = 'Pie';
            app.TextCheckBox = uicheckbox(app.GraphappearancemodificationsPanel);
            app.TextCheckBox.ValueChangedFcn = createCallbackFcn(app, @TextCheckBoxValueChanged, true);
            app.TextCheckBox.Text = 'Text';
            app.PercentagesCheckBox = uicheckbox(app.GraphappearancemodificationsPanel);
            app.PercentagesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PercentagesCheckBoxValueChanged, true);
            app.PercentagesCheckBox.Text = 'Percentages';
            app.NBSSSwitchLabel = uilabel(app.GraphappearancemodificationsPanel);
            app.NBSSSwitchLabel.Text = 'NBSS';
            app.NBSSSwitch = uiswitch(app.GraphappearancemodificationsPanel, 'slider');
            app.NBSSSwitch.Items = {'surface', 'bar'};
            app.NBSSSwitch.ValueChangedFcn = createCallbackFcn(app, @NBSSSwitchValueChanged, true);
            app.NBSSSwitch.Value = 'surface';
            app.ESDminEditFieldLabel = uilabel(app.GraphappearancemodificationsPanel);
            app.ESDminEditFieldLabel.Text = 'ESDmin';
            app.ESDminEditField = uieditfield(app.GraphappearancemodificationsPanel, 'numeric');
            app.ESDminEditField.ValueChangedFcn = createCallbackFcn(app, @ESDminEditFieldValueChanged, true);
            app.ESDminEditField.Value = 0.1;
            app.ESDmaxEditFieldLabel = uilabel(app.GraphappearancemodificationsPanel);
            app.ESDmaxEditFieldLabel.Text = 'ESDmax';
            app.ESDmaxEditField = uieditfield(app.GraphappearancemodificationsPanel, 'numeric');
            app.ESDmaxEditField.ValueChangedFcn = createCallbackFcn(app, @ESDmaxEditFieldValueChanged, true);
            app.ESDmaxEditField.Value = 10000;
            app.ColormapfunctionalListBoxLabel = uilabel(app.GraphappearancemodificationsPanel);
            app.ColormapfunctionalListBoxLabel.Text = 'Colormap functional';
            app.ColormapfunctionalListBox = uilistbox(app.GraphappearancemodificationsPanel);
            app.ColormaptrophicListBoxLabel = uilabel(app.GraphappearancemodificationsPanel);
            app.ColormaptrophicListBoxLabel.Text = 'Colormap trophic';
            app.ColormaptrophicListBox = uilistbox(app.GraphappearancemodificationsPanel);
            app.NBSSCheckBox = uicheckbox(app.GraphappearancemodificationsPanel);
            app.NBSSCheckBox.ValueChangedFcn = createCallbackFcn(app, @NBSSCheckBoxValueChanged, true);
            app.NBSSCheckBox.Text = 'NBSS';
            app.SavePanel = uipanel(app.ConfigurationPanel);
            app.SavePanel.Title = 'Save';
            app.SavegraphButton = uibutton(app.SavePanel, 'push');
            app.SavegraphButton.ButtonPushedFcn = createCallbackFcn(app, @SavegraphButtonPushed, true);
            app.SavegraphButton.Text = 'Save graph';
            app.ResolutionSliderLabel = uilabel(app.SavePanel);
            app.ResolutionSliderLabel.Text = 'Resolution';
            app.ResolutionSlider = uislider(app.SavePanel);
            app.TypeoffileListBoxLabel = uilabel(app.SavePanel);
            app.TypeoffileListBoxLabel.Text = 'Type of file';
            app.TypeoffileListBox = uilistbox(app.SavePanel);
            app.TypeoffileListBox.Items = {'jpg', 'svg'};
            app.TypeoffileListBox.Value = 'jpg';
            app.AllsamplessequentiallyButton = uibutton(app.SavePanel, 'state');
            app.AllsamplessequentiallyButton.Text = 'All samples sequentially';
            app.GroupstoexcludePanel_2 = uipanel(app.inspectexistingprocessedfilesTab);
            app.GroupstoexcludePanel_2.Title = 'Groups to exclude';
            app.trophicgroupstoexcludeListBoxLabel = uilabel(app.GroupstoexcludePanel_2);
            app.trophicgroupstoexcludeListBoxLabel.Text = 'trophic groups to exclude';
            app.trophicgroupstoexcludeListBox = uilistbox(app.GroupstoexcludePanel_2);
            app.trophicgroupstoexcludeListBox.Multiselect = 'on';
            app.trophicgroupstoexcludeListBox.ValueChangedFcn = createCallbackFcn(app, @trophicgroupstoexcludeListBoxValueChanged, true);
            app.trophicgroupstoexcludeListBox.Value = {};
            app.functionalgroupstoexcludeListBoxLabel = uilabel(app.GroupstoexcludePanel_2);
            app.functionalgroupstoexcludeListBoxLabel.Text = 'functional groups to exclude';
            app.functionalgroupstoexcludeListBox = uilistbox(app.GroupstoexcludePanel_2);
            app.functionalgroupstoexcludeListBox.Multiselect = 'on';
            app.functionalgroupstoexcludeListBox.ValueChangedFcn = createCallbackFcn(app, @functionalgroupstoexcludeListBoxValueChanged, true);
            app.functionalgroupstoexcludeListBox.Value = {};
            app.detailledgroupstoexcludeListBoxLabel = uilabel(app.GroupstoexcludePanel_2);
            app.detailledgroupstoexcludeListBoxLabel.Text = 'detailled groups to exclude';
            app.detailledgroupstoexcludeListBox = uilistbox(app.GroupstoexcludePanel_2);
            app.detailledgroupstoexcludeListBox.Multiselect = 'on';
            app.detailledgroupstoexcludeListBox.ValueChangedFcn = createCallbackFcn(app, @detailledgroupstoexcludeListBoxValueChanged, true);
            app.detailledgroupstoexcludeListBox.Value = {};
            app.OriginalidentificationLabel = uilabel(app.inspectexistingprocessedfilesTab);
            app.OriginalidentificationLabel.Text = 'Original identification';
            app.functionaltypesLabel = uilabel(app.inspectexistingprocessedfilesTab);
            app.functionaltypesLabel.Text = 'functional types';
            app.AbundancesLabel = uilabel(app.inspectexistingprocessedfilesTab);
            app.AbundancesLabel.Text = 'Abundances';
            app.TrophictypesLabel = uilabel(app.inspectexistingprocessedfilesTab);
            app.TrophictypesLabel.Text = 'Trophic types';
            app.BiovolumesLabel = uilabel(app.inspectexistingprocessedfilesTab);
            app.BiovolumesLabel.Text = 'Biovolumes';
            app.NBSSLabel = uilabel(app.inspectexistingprocessedfilesTab);
            app.NBSSLabel.Text = 'NBSS';
            app.UIAxes = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_4 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_4, '')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.UIAxes_5 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_5, '')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            app.UIAxes_6 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_6, '')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            app.UIAxes_7 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_7, '')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            app.UIAxes_8 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_8, '')
            xlabel(app.UIAxes_8, 'X')
            ylabel(app.UIAxes_8, 'Y')
            app.UIAxes_9 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes_9, '')
            xlabel(app.UIAxes_9, 'X')
            ylabel(app.UIAxes_9, 'Y')
            app.LegendLabel = uilabel(app.inspectexistingprocessedfilesTab);
            app.LegendLabel.Text = 'Legend';
            app.UIAxes2 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2_2 = uiaxes(app.inspectexistingprocessedfilesTab);
            title(app.UIAxes2_2, '')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.GlobalrawanalysisTab = uitab(app.TabGroup);
            app.GlobalrawanalysisTab.Title = 'Global raw analysis';
            app.typeofanalysisPanel = uipanel(app.GlobalrawanalysisTab);
            app.typeofanalysisPanel.Title = 'type of analysis';
            app.ScaleSwitchLabel = uilabel(app.typeofanalysisPanel);
            app.ScaleSwitchLabel.Text = 'Scale';
            app.ScaleSwitch = uiswitch(app.typeofanalysisPanel, 'slider');
            app.ScaleSwitch.Items = {'Spatial', 'Temporal'};
            app.ScaleSwitch.Value = 'Spatial';
            app.SpeciesListBoxLabel = uilabel(app.typeofanalysisPanel);
            app.SpeciesListBoxLabel.Text = 'Species';
            app.SpeciesListBox = uilistbox(app.typeofanalysisPanel);
            app.SpeciesListBox.ValueChangedFcn = createCallbackFcn(app, @SpeciesListBoxValueChanged, true);
            app.unitsSwitchLabel = uilabel(app.typeofanalysisPanel);
            app.unitsSwitchLabel.Text = 'units';
            app.unitsSwitch = uiswitch(app.typeofanalysisPanel, 'slider');
            app.unitsSwitch.Items = {'Ab', 'Bv'};
            app.unitsSwitch.Value = 'Ab';
            app.RangePanel = uipanel(app.GlobalrawanalysisTab);
            app.RangePanel.Title = 'Range';
            app.MinlatEditFieldLabel = uilabel(app.RangePanel);
            app.MinlatEditFieldLabel.Text = 'Min lat';
            app.MinlatEditField = uieditfield(app.RangePanel, 'numeric');
            app.MinlatEditField.Value = -180;
            app.MinlonEditFieldLabel = uilabel(app.RangePanel);
            app.MinlonEditFieldLabel.Text = 'Min lon';
            app.MinlonEditField = uieditfield(app.RangePanel, 'numeric');
            app.MinlonEditField.Value = -90;
            app.MaxlatEditFieldLabel = uilabel(app.RangePanel);
            app.MaxlatEditFieldLabel.Text = 'Max lat';
            app.MaxlatEditField = uieditfield(app.RangePanel, 'numeric');
            app.MaxlatEditField.Value = 180;
            app.MaxlonEditFieldLabel = uilabel(app.RangePanel);
            app.MaxlonEditFieldLabel.Text = 'Max lon';
            app.MaxlonEditField = uieditfield(app.RangePanel, 'numeric');
            app.MaxlonEditField.Value = 90;
            app.ProjectionListBoxLabel = uilabel(app.RangePanel);
            app.ProjectionListBoxLabel.Text = 'Projection';
            app.ProjectionListBox = uilistbox(app.RangePanel);
            app.GraphicoutputsPanel = uipanel(app.GlobalrawanalysisTab);
            app.GraphicoutputsPanel.Title = 'Graphic outputs';
            app.SizeofpointsEditFieldLabel = uilabel(app.GraphicoutputsPanel);
            app.SizeofpointsEditFieldLabel.Text = 'Size of points';
            app.SizeofpointsEditField = uieditfield(app.GraphicoutputsPanel, 'numeric');
            app.ColormapListBoxLabel = uilabel(app.GraphicoutputsPanel);
            app.ColormapListBoxLabel.Text = 'Colormap';
            app.ColormapListBox = uilistbox(app.GraphicoutputsPanel);
            app.LogscaleCheckBox = uicheckbox(app.GraphicoutputsPanel);
            app.LogscaleCheckBox.Text = 'Log scale';
            app.LogscaleCheckBox.Value = true;
            app.DrawButton = uibutton(app.GraphicoutputsPanel, 'push');
            app.DrawButton.Text = 'Draw';
            app.UIAxes3 = uiaxes(app.GlobalrawanalysisTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = exotaxa_inspect5

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end